classdef MATLABCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATLABCalculatorCopyrightCHurrieam2020UIFigure  matlab.ui.Figure
        Button_CUBE      matlab.ui.control.Button
        Button_TAN       matlab.ui.control.Button
        Button_COS       matlab.ui.control.Button
        Button_SIN       matlab.ui.control.Button
        Button_PI        matlab.ui.control.Button
        Button_AllClear  matlab.ui.control.Button
        Button_RESULT    matlab.ui.control.Button
        Button_RIGHT     matlab.ui.control.Button
        Button_LEFT      matlab.ui.control.Button
        Button_SQRT      matlab.ui.control.Button
        Button_DIVIDE    matlab.ui.control.Button
        Button_MULTIPLY  matlab.ui.control.Button
        Button_MINUS     matlab.ui.control.Button
        Button_ADD       matlab.ui.control.Button
        Button_POINT     matlab.ui.control.Button
        Button_0         matlab.ui.control.Button
        Button_9         matlab.ui.control.Button
        Button_8         matlab.ui.control.Button
        Button_7         matlab.ui.control.Button
        Button_6         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button_1         matlab.ui.control.Button
        Display          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.Display.Text = app.Display.Text + "1";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Display.Text = app.Display.Text + "2";
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Display.Text = app.Display.Text + "3";
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Display.Text = app.Display.Text + "4";
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Display.Text = app.Display.Text + "5";
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Display.Text = app.Display.Text + "6";
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Display.Text = app.Display.Text + "7";
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Display.Text = app.Display.Text + "8";
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Display.Text = app.Display.Text + "9";
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            app.Display.Text = app.Display.Text + "0";
        end

        % Button pushed function: Button_POINT
        function Button_POINTPushed(app, event)
            app.Display.Text = app.Display.Text + ".";
        end

        % Button pushed function: Button_ADD
        function Button_ADDPushed(app, event)
            app.Display.Text = app.Display.Text + "+";
        end

        % Button pushed function: Button_MINUS
        function Button_MINUSPushed(app, event)
            app.Display.Text = app.Display.Text + "-";
        end

        % Button pushed function: Button_MULTIPLY
        function Button_MULTIPLYPushed(app, event)
            app.Display.Text = app.Display.Text + "*";
        end

        % Button pushed function: Button_DIVIDE
        function Button_DIVIDEPushed(app, event)
            app.Display.Text = app.Display.Text + "/";
        end

        % Button pushed function: Button_SQRT
        function Button_SQRTPushed(app, event)
            app.Display.Text = app.Display.Text + "sqrt(";
        end

        % Button pushed function: Button_LEFT
        function Button_LEFTPushed(app, event)
            app.Display.Text = app.Display.Text + "(";
        end

        % Button pushed function: Button_RIGHT
        function Button_RIGHTPushed(app, event)
            app.Display.Text = app.Display.Text + ")";
        end

        % Button pushed function: Button_RESULT
        function Button_RESULTPushed(app, event)
            try
                dsp = eval(app.Display.Text);
                app.Display.Text = string(dsp);
                if app.Display.Text == ""
                    msgbox("A wrong expression! ");
                end
            catch
                msgbox("A wrong expression! ");
            end
        end

        % Button pushed function: Button_AllClear
        function Button_AllClearPushed(app, event)
            app.Display.Text = "";
        end

        % Button pushed function: Button_PI
        function Button_PIPushed(app, event)
            app.Display.Text = app.Display.Text + "pi";
        end

        % Button pushed function: Button_SIN
        function Button_SINPushed(app, event)
            app.Display.Text = app.Display.Text + "sin(";
        end

        % Button pushed function: Button_COS
        function Button_COSPushed(app, event)
            app.Display.Text = app.Display.Text + "cos(";
        end

        % Button pushed function: Button_TAN
        function Button_TANPushed(app, event)
            app.Display.Text = app.Display.Text + "tan(";
        end

        % Button pushed function: Button_CUBE
        function Button_CUBEPushed(app, event)
            app.Display.Text = app.Display.Text + "^";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MATLABCalculatorCopyrightCHurrieam2020UIFigure = uifigure('Visible', 'off');
            app.MATLABCalculatorCopyrightCHurrieam2020UIFigure.Name = 'MATLABCalculator Copyright (C) Hurrieam 2020';
            app.Display = uilabel(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure);
            app.Display.Text = '';
            app.Button_1 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_0 = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.Button_POINT = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_POINT.ButtonPushedFcn = createCallbackFcn(app, @Button_POINTPushed, true);
            app.Button_POINT.Text = '.';
            app.Button_ADD = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_ADD.ButtonPushedFcn = createCallbackFcn(app, @Button_ADDPushed, true);
            app.Button_ADD.Text = '+';
            app.Button_MINUS = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_MINUS.ButtonPushedFcn = createCallbackFcn(app, @Button_MINUSPushed, true);
            app.Button_MINUS.Text = '-';
            app.Button_MULTIPLY = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_MULTIPLY.ButtonPushedFcn = createCallbackFcn(app, @Button_MULTIPLYPushed, true);
            app.Button_MULTIPLY.Text = '×';
            app.Button_DIVIDE = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_DIVIDE.ButtonPushedFcn = createCallbackFcn(app, @Button_DIVIDEPushed, true);
            app.Button_DIVIDE.Text = '÷';
            app.Button_SQRT = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_SQRT.ButtonPushedFcn = createCallbackFcn(app, @Button_SQRTPushed, true);
            app.Button_SQRT.Text = '√';
            app.Button_LEFT = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_LEFT.ButtonPushedFcn = createCallbackFcn(app, @Button_LEFTPushed, true);
            app.Button_LEFT.Text = '(';
            app.Button_RIGHT = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_RIGHT.ButtonPushedFcn = createCallbackFcn(app, @Button_RIGHTPushed, true);
            app.Button_RIGHT.Text = ')';
            app.Button_RESULT = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_RESULT.ButtonPushedFcn = createCallbackFcn(app, @Button_RESULTPushed, true);
            app.Button_RESULT.Text = '=';
            app.Button_AllClear = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_AllClear.ButtonPushedFcn = createCallbackFcn(app, @Button_AllClearPushed, true);
            app.Button_AllClear.Text = 'AC';
            app.Button_PI = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_PI.ButtonPushedFcn = createCallbackFcn(app, @Button_PIPushed, true);
            app.Button_PI.Text = 'π';
            app.Button_SIN = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_SIN.ButtonPushedFcn = createCallbackFcn(app, @Button_SINPushed, true);
            app.Button_SIN.Text = 'sin';
            app.Button_COS = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_COS.ButtonPushedFcn = createCallbackFcn(app, @Button_COSPushed, true);
            app.Button_COS.Text = 'cos';
            app.Button_TAN = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_TAN.ButtonPushedFcn = createCallbackFcn(app, @Button_TANPushed, true);
            app.Button_TAN.Text = 'tan';
            app.Button_CUBE = uibutton(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure, 'push');
            app.Button_CUBE.ButtonPushedFcn = createCallbackFcn(app, @Button_CUBEPushed, true);
            app.Button_CUBE.Text = '^';
            % Show the figure after all components are created
            app.MATLABCalculatorCopyrightCHurrieam2020UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLABCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATLABCalculatorCopyrightCHurrieam2020UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATLABCalculatorCopyrightCHurrieam2020UIFigure)
        end
    end
end