classdef ChickenRabbit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChickenRabbitCopyrightCHurrieam2020UIFigure  matlab.ui.Figure
        SolveButton                    matlab.ui.control.Button
        theNumberofFeetEditField       matlab.ui.control.NumericEditField
        theNumberofFeetEditFieldLabel  matlab.ui.control.Label
        theNumberofHeadEditField       matlab.ui.control.NumericEditField
        theNumberofHeadEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            Head = app.theNumberofHeadEditField.Value;
            Feet = app.theNumberofFeetEditField.Value;
            
            syms Chicken Rabbit;
            
            eq1 = Head == Chicken + Rabbit;
            eq2 = Feet == 2 * Chicken + 4 * Rabbit;
            
            [Chicken, Rabbit] = solve([eq1, eq2], [Chicken, Rabbit]);
            
            msgbox('the Number of Chicken  = ' + string(Chicken) + " , the Number of Rabbit = " + string(Rabbit) + " . ", "Result");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChickenRabbitCopyrightCHurrieam2020UIFigure = uifigure('Visible', 'off');
            app.ChickenRabbitCopyrightCHurrieam2020UIFigure.Name = 'ChickenRabbit Copyright (C) Hurrieam 2020';
            app.theNumberofHeadEditFieldLabel = uilabel(app.ChickenRabbitCopyrightCHurrieam2020UIFigure);
            app.theNumberofHeadEditFieldLabel.Text = 'the Number of Head';
            app.theNumberofHeadEditField = uieditfield(app.ChickenRabbitCopyrightCHurrieam2020UIFigure, 'numeric');
            app.theNumberofFeetEditFieldLabel = uilabel(app.ChickenRabbitCopyrightCHurrieam2020UIFigure);
            app.theNumberofFeetEditFieldLabel.Text = 'the Number of Feet';
            app.theNumberofFeetEditField = uieditfield(app.ChickenRabbitCopyrightCHurrieam2020UIFigure, 'numeric');
            app.SolveButton = uibutton(app.ChickenRabbitCopyrightCHurrieam2020UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            % Show the figure after all components are created
            app.ChickenRabbitCopyrightCHurrieam2020UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChickenRabbit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChickenRabbitCopyrightCHurrieam2020UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChickenRabbitCopyrightCHurrieam2020UIFigure)
        end
    end
end